classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        HorizontalSliderLabel     matlab.ui.control.Label
        HorizontalSlider          matlab.ui.control.Slider
        VerticalSliderLabel       matlab.ui.control.Label
        VerticalSlider            matlab.ui.control.Slider
        HorizontalangleKnobLabel  matlab.ui.control.Label
        HorizontalangleKnob       matlab.ui.control.Knob
        VerticalangleKnobLabel    matlab.ui.control.Label
        VerticalangleKnob         matlab.ui.control.Knob
        CubeDimensionKnobLabel    matlab.ui.control.Label
        CubeDimensionKnob         matlab.ui.control.Knob
    end

    
    properties (Access = private)
        rubikDim = 3;
        cubeW = 100;
        
        % Camera
        aspectRatio = 1.7777777777;
        angle = 1.0471976 * 0.5; % camFOV * 0.5
        camFar = 4462.1959;
        camNear = 44.621959;
        
        ampli = 0;
        
        angX0 = 0.25 * pi;
        angZ0 = 0.25 * pi;
        trueIncX = 0;
        trueIncZ = 0;
        
        angX = 0;
        angZ = 0;
        
        
        % camera results:
        camRotationMatrix = 0;
    end
    
    methods (Access = private)
        
        function [matrix] = rotationMatrix3D(~, a, angle)
        % Returns the corresponding 3D rotation matrix
            if a == "X"
                matrix = [ ...
                    [1, 0, 0]; ...
                    [0, cos(angle), -sin(angle)]; ...
                    [0, sin(angle), cos(angle)] ...
                ];
            elseif a == "Y"
                matrix = [ ...
                    [cos(-angle), 0, sin(-angle)]; ...
                    [0, 1, 0]; ...
                    [-sin(-angle), 0, cos(-angle)] ...
                ];
            else
                matrix = [ ...
                    [cos(angle), -sin(angle), 0]; ...
                    [sin(angle), cos(angle), 0]; ...
                    [0, 0, 1] ...
                ];
            end
        end
        
        function [] = updatePlot(app)
            hold(app.UIAxes, 'on');
            % Draw visual help
            ejesX = [[1, -1]; [1, -1]; [0, 0]];
            ejesY = [[1, -1]; [-1, 1]; [0, 0]];
            ejesZ = [[0,  0]; [0,  0]; [sqrt(2), -sqrt(2)]];
            for x = 1:3
                plot3(app.UIAxes, ejesX(x, :) .* app.cubeW, ejesY(x, :) .* app.cubeW, ejesZ(x, :) .* app.cubeW, "k-");
            end
            hold(app.UIAxes, 'off');

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Setup variables
            app.ampli = 2^(0.25 * app.rubikDim) * 300;


            % Setup plot3 properties
            rotate3d(app.UIAxes,'on');
            mult = 1.1;
            axis(app.UIAxes, [-app.ampli * mult, app.ampli * mult, -app.ampli * mult, app.ampli * mult, -app.ampli * mult, app.ampli * mult])
            xlabel(app.UIAxes, "X"); ylabel(app.UIAxes, "Y"); zlabel(app.UIAxes, "Z");
            view(app.UIAxes, 135, 45)
            grid(app.UIAxes, 'MINOR');
            
            updatePlot(app)
        end

        % Value changing function: HorizontalSlider
        function HorizontalSliderValueChanging(app, event)
            changingValue = event.Value;
            
            updatePlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.HorizontalSliderLabel = uilabel(app.UIFigure);
            app.HorizontalSliderLabel.Text = 'Horizontal';
            app.HorizontalSlider = uislider(app.UIFigure);
            app.HorizontalSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.HorizontalSlider.ValueChangingFcn = createCallbackFcn(app, @HorizontalSliderValueChanging, true);
            app.VerticalSliderLabel = uilabel(app.UIFigure);
            app.VerticalSliderLabel.Text = 'Vertical';
            app.VerticalSlider = uislider(app.UIFigure);
            app.VerticalSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.VerticalSlider.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.VerticalSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.HorizontalangleKnobLabel = uilabel(app.UIFigure);
            app.HorizontalangleKnobLabel.Text = 'Horizontal angle';
            app.HorizontalangleKnob = uiknob(app.UIFigure, 'continuous');
            app.HorizontalangleKnob.Limits = [-6.28318530717959 6.28318530717959];
            app.HorizontalangleKnob.MajorTicks = [-6.28318530717959 -3.14159265358979 0 3.14159265358979 6.28318530717959];
            app.HorizontalangleKnob.MajorTickLabels = {'-2Π', '-Π', '0', 'Π', '2Π'};
            app.VerticalangleKnobLabel = uilabel(app.UIFigure);
            app.VerticalangleKnobLabel.Text = 'Vertical angle';
            app.VerticalangleKnob = uiknob(app.UIFigure, 'continuous');
            app.VerticalangleKnob.Limits = [-6.28318530717959 6.28318530717959];
            app.VerticalangleKnob.MajorTicks = [-6.28318530717959 -3.14159265358979 0 3.14159265358979 6.28318530717959];
            app.VerticalangleKnob.MajorTickLabels = {'-2Π', '-Π', '0', 'Π', '2Π'};
            app.CubeDimensionKnobLabel = uilabel(app.UIFigure);
            app.CubeDimensionKnobLabel.Text = 'Cube Dimension';
            app.CubeDimensionKnob = uiknob(app.UIFigure, 'continuous');
            app.CubeDimensionKnob.Limits = [2 10];
            app.CubeDimensionKnob.MajorTicks = [2 3 4 5 6 7 8 9 10];
            app.CubeDimensionKnob.MinorTicks = [];
            app.CubeDimensionKnob.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end